classdef initConditionPicker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WalkingRobotInitialConditionPickerUIFigure  matlab.ui.Figure
        RightLegLabel              matlab.ui.control.Label
        JointDampingNmradsLabel    matlab.ui.control.Label
        JointDampingEditField      matlab.ui.control.NumericEditField
        LeftLegLabel               matlab.ui.control.Label
        WalkingRobotInitialConditionPickerLabel  matlab.ui.control.Label
        AllanglesinradiansLabel    matlab.ui.control.Label
        SimulateButton             matlab.ui.control.Button
        AnkleRollEditFieldLabel    matlab.ui.control.Label
        AnkleRollEditField_R       matlab.ui.control.EditField
        AnklePitchLabel            matlab.ui.control.Label
        AnklePitchEditField_R      matlab.ui.control.EditField
        KneeLabel                  matlab.ui.control.Label
        KneeEditField_R            matlab.ui.control.EditField
        HipYawLabel                matlab.ui.control.Label
        HipYawEditField_R          matlab.ui.control.EditField
        HipRollLabel               matlab.ui.control.Label
        HipRollEditField_R         matlab.ui.control.EditField
        HipPitchLabel              matlab.ui.control.Label
        HipPitchEditField_R        matlab.ui.control.EditField
        HipYawLabel_2              matlab.ui.control.Label
        HipYawEditField_L          matlab.ui.control.EditField
        HipRollLabel_2             matlab.ui.control.Label
        HipRollEditField_L         matlab.ui.control.EditField
        HipPitchLabel_2            matlab.ui.control.Label
        HipPitchEditField_L        matlab.ui.control.EditField
        KneeLabel_2                matlab.ui.control.Label
        KneeEditField_L            matlab.ui.control.EditField
        AnklePitchLabel_2          matlab.ui.control.Label
        AnklePitchEditField_L      matlab.ui.control.EditField
        AnkleRollEditFieldLabel_2  matlab.ui.control.Label
        AnkleRollEditField_L       matlab.ui.control.EditField
    end

    % Walking Robot Initial Condition Picker App
    % Copyright 2019 The MathWorks, Inc.

    properties (Access = public)
        mdlName = 'walkingRobot_step1_mechanics';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            % Set right leg joint angles
            set_param([app.mdlName '/Robot Leg R/Ankle Roll Joint'],'PositionTargetValue',app.AnkleRollEditField_R.Value);
            set_param([app.mdlName '/Robot Leg R/Ankle Pitch Joint'],'PositionTargetValue',"-" + app.AnklePitchEditField_R.Value);
            set_param([app.mdlName '/Robot Leg R/Knee Joint'],'PositionTargetValue',"-" + app.KneeEditField_R.Value);
            set_param([app.mdlName '/Robot Leg R/Hip Pitch Joint'],'PositionTargetValue',"-" + app.HipPitchEditField_R.Value);
            set_param([app.mdlName '/Robot Leg R/Hip Roll Joint'],'PositionTargetValue',app.HipRollEditField_R.Value);
            set_param([app.mdlName '/Robot Leg R/Hip Yaw Joint'],'PositionTargetValue',app.HipYawEditField_R.Value);
            
            % Set left leg joint angles
            set_param([app.mdlName '/Robot Leg L/Ankle Roll Joint'],'PositionTargetValue',app.AnkleRollEditField_L.Value);
            set_param([app.mdlName '/Robot Leg L/Ankle Pitch Joint'],'PositionTargetValue',"-" + app.AnklePitchEditField_L.Value);
            set_param([app.mdlName '/Robot Leg L/Knee Joint'],'PositionTargetValue',"-" + app.KneeEditField_L.Value);
            set_param([app.mdlName '/Robot Leg L/Hip Pitch Joint'],'PositionTargetValue',"-" + app.HipPitchEditField_L.Value);
            set_param([app.mdlName '/Robot Leg L/Hip Roll Joint'],'PositionTargetValue',app.HipRollEditField_L.Value);
            set_param([app.mdlName '/Robot Leg L/Hip Yaw Joint'],'PositionTargetValue',app.HipYawEditField_L.Value);
            
            % Set joint damping
            allJoints = find_system(app.mdlName,'MaskType','Revolute Joint');
            for idx = 1:numel(allJoints)
                set_param(allJoints{idx},'DampingCoefficient',num2str(app.JointDampingEditField.Value));
            end
            
            % Run the model
            sim(app.mdlName);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WalkingRobotInitialConditionPickerUIFigure = uifigure('Visible', 'off');
            app.WalkingRobotInitialConditionPickerUIFigure.Name = 'Walking Robot Initial Condition Picker';
            app.RightLegLabel = uilabel(app.WalkingRobotInitialConditionPickerUIFigure);
            app.RightLegLabel.Text = 'Right Leg';
            app.JointDampingNmradsLabel = uilabel(app.WalkingRobotInitialConditionPickerUIFigure);
            app.JointDampingNmradsLabel.Text = 'Joint Damping [N*m/(rad/s)]';
            app.JointDampingEditField = uieditfield(app.WalkingRobotInitialConditionPickerUIFigure, 'numeric');
            app.JointDampingEditField.Value = 0.01;
            app.LeftLegLabel = uilabel(app.WalkingRobotInitialConditionPickerUIFigure);
            app.LeftLegLabel.Text = 'Left Leg';
            app.WalkingRobotInitialConditionPickerLabel = uilabel(app.WalkingRobotInitialConditionPickerUIFigure);
            app.WalkingRobotInitialConditionPickerLabel.Text = 'Walking Robot Initial Condition Picker';
            app.AllanglesinradiansLabel = uilabel(app.WalkingRobotInitialConditionPickerUIFigure);
            app.AllanglesinradiansLabel.Text = 'All angles in radians';
            app.SimulateButton = uibutton(app.WalkingRobotInitialConditionPickerUIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.AnkleRollEditFieldLabel = uilabel(app.WalkingRobotInitialConditionPickerUIFigure);
            app.AnkleRollEditFieldLabel.Text = 'Ankle Roll';
            app.AnkleRollEditField_R = uieditfield(app.WalkingRobotInitialConditionPickerUIFigure, 'text');
            app.AnkleRollEditField_R.Value = '0';
            app.AnklePitchLabel = uilabel(app.WalkingRobotInitialConditionPickerUIFigure);
            app.AnklePitchLabel.Text = 'Ankle Pitch';
            app.AnklePitchEditField_R = uieditfield(app.WalkingRobotInitialConditionPickerUIFigure, 'text');
            app.AnklePitchEditField_R.Value = '0';
            app.KneeLabel = uilabel(app.WalkingRobotInitialConditionPickerUIFigure);
            app.KneeLabel.Text = 'Knee';
            app.KneeEditField_R = uieditfield(app.WalkingRobotInitialConditionPickerUIFigure, 'text');
            app.KneeEditField_R.Value = 'pi/4';
            app.HipYawLabel = uilabel(app.WalkingRobotInitialConditionPickerUIFigure);
            app.HipYawLabel.Text = 'Hip Yaw';
            app.HipYawEditField_R = uieditfield(app.WalkingRobotInitialConditionPickerUIFigure, 'text');
            app.HipYawEditField_R.Value = '0';
            app.HipRollLabel = uilabel(app.WalkingRobotInitialConditionPickerUIFigure);
            app.HipRollLabel.Text = 'Hip Roll';
            app.HipRollEditField_R = uieditfield(app.WalkingRobotInitialConditionPickerUIFigure, 'text');
            app.HipRollEditField_R.Value = '0';
            app.HipPitchLabel = uilabel(app.WalkingRobotInitialConditionPickerUIFigure);
            app.HipPitchLabel.Text = 'Hip Pitch';
            app.HipPitchEditField_R = uieditfield(app.WalkingRobotInitialConditionPickerUIFigure, 'text');
            app.HipPitchEditField_R.Value = 'pi/4';
            app.HipYawLabel_2 = uilabel(app.WalkingRobotInitialConditionPickerUIFigure);
            app.HipYawLabel_2.Text = 'Hip Yaw';
            app.HipYawEditField_L = uieditfield(app.WalkingRobotInitialConditionPickerUIFigure, 'text');
            app.HipYawEditField_L.Value = '0';
            app.HipRollLabel_2 = uilabel(app.WalkingRobotInitialConditionPickerUIFigure);
            app.HipRollLabel_2.Text = 'Hip Roll';
            app.HipRollEditField_L = uieditfield(app.WalkingRobotInitialConditionPickerUIFigure, 'text');
            app.HipRollEditField_L.Value = '0';
            app.HipPitchLabel_2 = uilabel(app.WalkingRobotInitialConditionPickerUIFigure);
            app.HipPitchLabel_2.Text = 'Hip Pitch';
            app.HipPitchEditField_L = uieditfield(app.WalkingRobotInitialConditionPickerUIFigure, 'text');
            app.HipPitchEditField_L.Value = '-pi/4';
            app.KneeLabel_2 = uilabel(app.WalkingRobotInitialConditionPickerUIFigure);
            app.KneeLabel_2.Text = 'Knee';
            app.KneeEditField_L = uieditfield(app.WalkingRobotInitialConditionPickerUIFigure, 'text');
            app.KneeEditField_L.Value = 'pi/4';
            app.AnklePitchLabel_2 = uilabel(app.WalkingRobotInitialConditionPickerUIFigure);
            app.AnklePitchLabel_2.Text = 'Ankle Pitch';
            app.AnklePitchEditField_L = uieditfield(app.WalkingRobotInitialConditionPickerUIFigure, 'text');
            app.AnklePitchEditField_L.Value = '0';
            app.AnkleRollEditFieldLabel_2 = uilabel(app.WalkingRobotInitialConditionPickerUIFigure);
            app.AnkleRollEditFieldLabel_2.Text = 'Ankle Roll';
            app.AnkleRollEditField_L = uieditfield(app.WalkingRobotInitialConditionPickerUIFigure, 'text');
            app.AnkleRollEditField_L.Value = '0';
            % Show the figure after all components are created
            app.WalkingRobotInitialConditionPickerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = initConditionPicker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WalkingRobotInitialConditionPickerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WalkingRobotInitialConditionPickerUIFigure)
        end
    end
end